classdef alignHistology2OCT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FiguresPanel                matlab.ui.container.Panel
        UIAxesOCT                   matlab.ui.control.UIAxes
        UIAxesHistology             matlab.ui.control.UIAxes
        LoadDataPanel               matlab.ui.container.Panel
        SubjectPathEditFieldLabel   matlab.ui.control.Label
        SubjectPathEditField        matlab.ui.control.EditField
        LoadButton                  matlab.ui.control.Button
        SaveSectionButton           matlab.ui.control.Button
        SectionDropDownLabel        matlab.ui.control.Label
        SectionDropDown             matlab.ui.control.DropDown
        CrossPlaneAlignmentPanel    matlab.ui.container.Panel
        YPositionumSpinnerLabel     matlab.ui.control.Label
        YPositionumSpinner          matlab.ui.control.Spinner
        AlongPlaneAlignmentPanel    matlab.ui.container.Panel
        ScaleumpixSpinnerLabel      matlab.ui.control.Label
        ScaleumpixSpinner           matlab.ui.control.Spinner
        RotationdegSpinnerLabel     matlab.ui.control.Label
        RotationdegSpinner          matlab.ui.control.Spinner
        XTranslationumSpinnerLabel  matlab.ui.control.Label
        XTranslationumSpinner       matlab.ui.control.Spinner
        ZTranslationumSpinnerLabel  matlab.ui.control.Label
        ZTranslationumSpinner       matlab.ui.control.Spinner
        ReverttoBestEstimateButton  matlab.ui.control.Button
        OverlaySettingsPanel        matlab.ui.container.Panel
        TransparancyKnobLabel       matlab.ui.control.Label
        TransparancyKnob            matlab.ui.control.Knob
        BaseImageSwitchLabel        matlab.ui.control.Label
        BaseImageSwitch             matlab.ui.control.Switch
        AutoFineAlignButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        subjectPath = ''; % Subject's path been loaded
        
        scJsonFilePath = ''; % Path to stack config JSON
        scJson = ''; %Stack config JSON
        
        slidePath = '';
        slideConfigPath = '';
        slideConfigJson = [];
        
        activeSectionIndex = [];
        activeSectionIteration = [];
        
        stackVolumeIterationConfig_um = [];
        stackVolumeIterationFilePath = '';
        
        histologyFilePath = '';
        histologyIm = [];
        
        brightFieldFilePath = '';
        brightFieldIm = [];
    end
    
    methods (Access = private)
        
        % Helper function to determine path to stack volume
        function p = getStackVolume_IterationPath (app, iteration)
            p = awsModifyPathForCompetability(sprintf('%s/OCTVolumes/StackVolume_Iteration%d/',...
                app.subjectPath,iteration));
        end
        
        % Helpfer function to convert deafult position to knobes position
        function setKnobesToDefaultPosition(app)
            % Figure out output parameters
            xOut = app.stackVolumeIterationConfig_um.x.values;
            zOut = app.stackVolumeIterationConfig_um.z.values;
            dx = diff(xOut(1:2));
            dz = diff(zOut(1:2));
            if std(diff(xOut))>1e-3 || std(diff(zOut))>1e-3 || dx ~= dz
                error('Unimplemented here');
            end
            
            histologyToOCTT = octSlideToTransform( ...
                app.slideConfigJson.FM.singlePlaneFit.u, ...
                app.slideConfigJson.FM.singlePlaneFit.v, ...
                app.slideConfigJson.FM.singlePlaneFit.h, ...
                app.stackVolumeIterationConfig_um.new2OriginalAffineTransform, ...
                xOut(1)*1e-3, ...
                zOut(1)*1e-3, ...
                app.slideConfigJson.FM.pixelSize_um, ...
                dx);
            
            [app.ScaleumpixSpinner.Value, ...
                app.RotationdegSpinner.Value,...
                app.XTranslationumSpinner.Value,...
                app.ZTranslationumSpinner.Value] = transform2Knobes(histologyToOCTT,dx);
        end
        
        % Get the OCT slide
        function imOCT = getOCTSlide (app)
            % Which slice is the closest
            ys = app.stackVolumeIterationConfig_um.y.values;
            d = abs(app.YPositionumSpinner.Value-ys);
            yI = find(d==min(d),1,'first');
            imOCT = octFromTifCash(app.stackVolumeIterationFilePath,yI);
        end
        
        % Helper function to move histology image to OCT
        function [imOCTOut,imHistologyOut,xValues,zValues] = histologyFineAlign(app)
            
            % Figure out output parameters
            xOut = app.stackVolumeIterationConfig_um.x.values;
            zOut = app.stackVolumeIterationConfig_um.z.values;
            dx = diff(xOut(1:2));
            dz = diff(zOut(1:2));
            if std(diff(xOut))>1e-3 || std(diff(zOut))>1e-3 || dx ~= dz
                error('Unimplemented here');
            end
            
            % Convert konbes to transform
            histologyToOCTT = knobesToTransform( ...
                app.ScaleumpixSpinner.Value, ...
                app.RotationdegSpinner.Value,...
                app.XTranslationumSpinner.Value,...
                app.ZTranslationumSpinner.Value,...
                dx);
            
            imOCT = getOCTSlide(app);
            
            isMoveHistologyToAlignWithOCT = strcmp(app.BaseImageSwitch.Value,"OCT");
            
            [imOCTOut,imHistologyOut] = overlayOCTHistology(imOCT,app.histologyIm,histologyToOCTT,...
                isMoveHistologyToAlignWithOCT);
            
            if (isMoveHistologyToAlignWithOCT)
                xValues = xOut;
                zValues = zOut;
            else
                xValues = 1:size(imOCT,2);
                zValues = 1:size(imOCT,1);
            end
        end
        
        % This method draws the main figure containing all data about OCT
        % and histology slides
        function drawFigure(app)
            if isempty(app.stackVolumeIterationFilePath)
                % Nothing was set yet, just return
                return;
            end
            
            imOCT = getOCTSlide(app);
            
            
            % Present OCT
            imagesc(...
                app.UIAxesOCT, ...
                app.stackVolumeIterationConfig_um.x.values*1e-3,...
                app.stackVolumeIterationConfig_um.z.values*1e-3,...
                imOCT);
            axis(app.UIAxesOCT,'equal');
            axis(app.UIAxesOCT,'ij');
            grid(app.UIAxesOCT,'on');
            %xlabel(app.UIAxesOCT,'x[mm]')
            %ylabel(app.UIAxesOCT,'z[mm]')
            
            % Convert histology
            [imOCTOut,imHistologyOut,xValuesHIm,zValuesHIm] = histologyFineAlign(app);
            
            % Present histology & OCT OVerlay
            % imagesc(hIm,'Parent',app.UIAxesHistology);
            imagesc(...
                app.UIAxesHistology, ...
                xValuesHIm*1e-3,...
                zValuesHIm*1e-3,...
                imOCTOut);
            hold(app.UIAxesHistology,'on');
            alpha = app.TransparancyKnob.Value/100;
            image(...
                'Parent',app.UIAxesHistology, ...
                'XData',xValuesHIm*1e-3,...
                'YData',zValuesHIm*1e-3,...
                'CData',imHistologyOut,...
                'AlphaData',alpha);
            hold(app.UIAxesHistology,'off');
            axis(app.UIAxesHistology,'equal');
            axis(app.UIAxesHistology,'ij');
            grid(app.UIAxesOCT,'on');
            %xlabel(app.UIAxesOCT,'x[mm]')
            %ylabel(app.UIAxesOCT,'z[mm]')
            %colormap(app.UIAxesHistology,'gray');
            
            %linkaxes([app.UIAxesOCT app.UIAxesHistology]);
            
            persistent setColormapOnce;
            if isempty(setColormapOnce)
                setColormapOnce = true;
                colormap(app.UIAxesOCT,'gray');
                colormap(app.UIAxesHistology,'gray'); %opens a figure.
            end  
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SubjectPathEditField.Value = s3SubjectPath('01'); % Default text is subject path
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Save current subject path.
            app.subjectPath = app.SubjectPathEditField.Value;
            
            % Load Stack config to better understand what slides are there
            app.scJsonFilePath = awsModifyPathForCompetability([app.subjectPath '/Slides/StackConfig.json']);
            app.scJson = awsReadJSON(app.scJsonFilePath);
            
            % Add options for user to select section
            app.SectionDropDown.Items = [{'<Choose Section>'}; app.scJson.sections.names(:)];
            app.SectionDropDown.Value = {'<Choose Section>'};
            
            % Bring other parameters to default position, to prevent
            % confusion
            app.activeSectionIteration = [];
            app.stackVolumeIterationFilePath = '';
            app.stackVolumeIterationConfig_um = [];
            app.YPositionumSpinner.Value = 0;
            app.histologyFilePath = '';
            app.histologyIm = [];
            app.brightFieldFilePath = '';
            app.brightFieldIm = [];
            app.slidePath = '';
            app.slideConfigPath = '';
            app.slideConfigJson = [];            
        end

        % Value changed function: SectionDropDown
        function SectionDropDownValueChanged(app, event)
            value = app.SectionDropDown.Value;
            
            tmp = cellfun(@(x)(strcmp(value,x)),app.scJson.sections.names);
            app.activeSectionIndex = find(tmp==1,1,'first');
            
            if isempty(app.activeSectionIndex)
                % Select no section, goodbye
                return;
            end
            
            % Determine iteration
            app.activeSectionIteration = app.scJson.sections.iterations(app.activeSectionIndex);
            
            % Determine stack volume iteration path & load configuration
            % file
            app.stackVolumeIterationFilePath = getStackVolume_IterationPath(app, app.activeSectionIteration);
            [~, app.stackVolumeIterationConfig_um] = ...
                yOCTFromTif(app.stackVolumeIterationFilePath,'isLoadMetadataOnly',true);
            app.stackVolumeIterationConfig_um = yOCTChangeDimensionsStructureUnits(app.stackVolumeIterationConfig_um,'um');
            
            % Get Slide config
            app.slidePath = awsModifyPathForCompetability(sprintf('%s/Slides/%s/',...
                app.subjectPath,...
                app.scJson.sections.names{app.activeSectionIndex}));
            app.slideConfigPath = [app.slidePath 'SlideConfig.json'];
            app.slideConfigJson = awsReadJSON([app.slidePath 'SlideConfig.json']);
            
            % Get Histology Slide path
            app.histologyFilePath = [app.slidePath app.slideConfigJson.histologyImageFilePath];
            
            % Read histology
            awsSetCredentials;
            ds = fileDatastore(app.histologyFilePath,'ReadFcn',@imread);
            app.histologyIm = ds.read();
            
            % Bright field
            app.brightFieldFilePath = [app.slidePath app.slideConfigJson.histologyImageFilePath];
            app.brightFieldIm = []; %Don't load now, we might not need it
            
            % Set knobs to default values
            setKnobesToDefaultPosition(app);
            
            % Finish by drawing image
            drawFigure(app);
        end

        % Value changed function: BaseImageSwitch, 
        % RotationdegSpinner, ScaleumpixSpinner, TransparancyKnob, 
        % XTranslationumSpinner, YPositionumSpinner, 
        % ZTranslationumSpinner
        function redraw(app, event)
            drawFigure(app);
        end

        % Button pushed function: ReverttoBestEstimateButton
        function ReverttoBestEstimateButtonPushed(app, event)
            % Set knobs to default values
            setKnobesToDefaultPosition(app)
            drawFigure(app);
        end

        % Button pushed function: AutoFineAlignButton
        function AutoFineAlignButtonPushed(app, event)
            
            % Load bright field image
            if isempty(app.brightFieldIm)
                ds = fileDatastore(app.brightFieldFilePath,'ReadFcn',@imread);
                app.brightFieldIm = ds.read();
            end
            
            % Marked Tissue Surface
            tissueInterfaceI = [app.slideConfigJson.FM.fiducialLines(:).group] == 't';
            app.slideConfigJson.FM.fiducialLines(tissueInterfaceI).u_pix;
            app.slideConfigJson.FM.fiducialLines(tissueInterfaceI).v_pix;
            
            drawFigure(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FiguresPanel = uipanel(app.UIFigure);
            app.FiguresPanel.Title = 'Figures';
            app.UIAxesOCT = uiaxes(app.FiguresPanel);
            title(app.UIAxesOCT, 'OCT')
            xlabel(app.UIAxesOCT, 'x [mm]')
            ylabel(app.UIAxesOCT, 'z [mm]')
            app.UIAxesHistology = uiaxes(app.FiguresPanel);
            title(app.UIAxesHistology, 'Histology')
            xlabel(app.UIAxesHistology, 'u [mm]')
            ylabel(app.UIAxesHistology, 'v [mm]')
            app.LoadDataPanel = uipanel(app.UIFigure);
            app.LoadDataPanel.Title = 'Load Data';
            app.SubjectPathEditFieldLabel = uilabel(app.LoadDataPanel);
            app.SubjectPathEditFieldLabel.Text = 'Subject Path:';
            app.SubjectPathEditField = uieditfield(app.LoadDataPanel, 'text');
            app.SubjectPathEditField.Value = '<Subject Path to Load>';
            app.LoadButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveSectionButton = uibutton(app.LoadDataPanel, 'push');
            app.SaveSectionButton.Text = 'Save Section';
            app.SectionDropDownLabel = uilabel(app.LoadDataPanel);
            app.SectionDropDownLabel.Text = 'Section:';
            app.SectionDropDown = uidropdown(app.LoadDataPanel);
            app.SectionDropDown.Items = {'<Choose Section>'};
            app.SectionDropDown.ValueChangedFcn = createCallbackFcn(app, @SectionDropDownValueChanged, true);
            app.SectionDropDown.Value = '<Choose Section>';
            app.CrossPlaneAlignmentPanel = uipanel(app.UIFigure);
            app.CrossPlaneAlignmentPanel.Title = 'Cross Plane Alignment';
            app.YPositionumSpinnerLabel = uilabel(app.CrossPlaneAlignmentPanel);
            app.YPositionumSpinnerLabel.Text = 'Y Position [um]';
            app.YPositionumSpinner = uispinner(app.CrossPlaneAlignmentPanel);
            app.YPositionumSpinner.Step = 5;
            app.YPositionumSpinner.ValueChangedFcn = createCallbackFcn(app, @redraw, true);
            app.AlongPlaneAlignmentPanel = uipanel(app.UIFigure);
            app.AlongPlaneAlignmentPanel.Title = 'Along Plane Alignment';
            app.ScaleumpixSpinnerLabel = uilabel(app.AlongPlaneAlignmentPanel);
            app.ScaleumpixSpinnerLabel.Text = 'Scale [um/pix]';
            app.ScaleumpixSpinner = uispinner(app.AlongPlaneAlignmentPanel);
            app.ScaleumpixSpinner.Step = 0.01;
            app.ScaleumpixSpinner.ValueChangedFcn = createCallbackFcn(app, @redraw, true);
            app.RotationdegSpinnerLabel = uilabel(app.AlongPlaneAlignmentPanel);
            app.RotationdegSpinnerLabel.Text = 'Rotation [deg]';
            app.RotationdegSpinner = uispinner(app.AlongPlaneAlignmentPanel);
            app.RotationdegSpinner.Step = 5;
            app.RotationdegSpinner.ValueChangedFcn = createCallbackFcn(app, @redraw, true);
            app.XTranslationumSpinnerLabel = uilabel(app.AlongPlaneAlignmentPanel);
            app.XTranslationumSpinnerLabel.Text = 'X Translation [um]';
            app.XTranslationumSpinner = uispinner(app.AlongPlaneAlignmentPanel);
            app.XTranslationumSpinner.Step = 10;
            app.XTranslationumSpinner.ValueChangedFcn = createCallbackFcn(app, @redraw, true);
            app.ZTranslationumSpinnerLabel = uilabel(app.AlongPlaneAlignmentPanel);
            app.ZTranslationumSpinnerLabel.Text = 'Z Translation [um]';
            app.ZTranslationumSpinner = uispinner(app.AlongPlaneAlignmentPanel);
            app.ZTranslationumSpinner.Step = 10;
            app.ZTranslationumSpinner.ValueChangedFcn = createCallbackFcn(app, @redraw, true);
            app.ReverttoBestEstimateButton = uibutton(app.UIFigure, 'push');
            app.ReverttoBestEstimateButton.ButtonPushedFcn = createCallbackFcn(app, @ReverttoBestEstimateButtonPushed, true);
            app.ReverttoBestEstimateButton.Text = 'Revert to Best Estimate';
            app.OverlaySettingsPanel = uipanel(app.UIFigure);
            app.OverlaySettingsPanel.Title = 'Overlay Settings';
            app.TransparancyKnobLabel = uilabel(app.OverlaySettingsPanel);
            app.TransparancyKnobLabel.Text = 'Transparancy';
            app.TransparancyKnob = uiknob(app.OverlaySettingsPanel, 'continuous');
            app.TransparancyKnob.MajorTicks = [0 50 100];
            app.TransparancyKnob.MajorTickLabels = {'OCT', '50/50 Blend', 'Hist'};
            app.TransparancyKnob.ValueChangedFcn = createCallbackFcn(app, @redraw, true);
            app.TransparancyKnob.MinorTicks = [0 25 50 75];
            app.TransparancyKnob.Value = 50;
            app.BaseImageSwitchLabel = uilabel(app.OverlaySettingsPanel);
            app.BaseImageSwitchLabel.Text = 'Base Image';
            app.BaseImageSwitch = uiswitch(app.OverlaySettingsPanel, 'slider');
            app.BaseImageSwitch.Items = {'Histology', 'OCT'};
            app.BaseImageSwitch.ValueChangedFcn = createCallbackFcn(app, @redraw, true);
            app.BaseImageSwitch.Value = 'OCT';
            app.AutoFineAlignButton = uibutton(app.UIFigure, 'push');
            app.AutoFineAlignButton.ButtonPushedFcn = createCallbackFcn(app, @AutoFineAlignButtonPushed, true);
            app.AutoFineAlignButton.Text = 'Auto Fine-Align';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = alignHistology2OCT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end